classdef uno < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        PlayerZNumber    matlab.ui.control.Label
        PlayerYNumber    matlab.ui.control.Label
        PlayerXNumber    matlab.ui.control.Label
        PlayerZ          matlab.ui.control.Image
        PlayerY          matlab.ui.control.Image
        PlayerX          matlab.ui.control.Image
        StartGameButton  matlab.ui.control.Button
        DrawCardLabel    matlab.ui.control.Label
        Image2           matlab.ui.control.Image
        Image            matlab.ui.control.Image
        YourHandPanel    matlab.ui.container.Panel
    end

    
    properties (Access = private)
        numPlayers;
        playerTurn;
        topCard;
        playersNumCards;
        topCardNum;
        preGame = 1;

        myPlayerNum;
        timestamp;
        handImages;
        promptWildButtons;

        channelID = 2364789;
        writeKey = 'I2UG9UCL0S6X3SCM';
        readKey = 'MJ8791RFGBHXVTG1';

    end
    
    properties (Access = public)
        deck;
        player;% Description
    end
    
    methods (Access = private)
        function [] = updateturn(app)
            %When calling this function provide both the current turn number from app
            %as well as the totalplayer number also from app
            if app.playerTurn < app.numPlayers
              app.playerTurn = app.playerTurn + 1;
            else
                app.playerTurn = 1;
            end
        end
        
        function writeData(app)
            %This function requires an input timestamp in date-time format.
            %the timestamp is the last time thingspeak was written to and
            %can be obtained from the function thingSpeakRead

            loop = true;
            while loop
                [~, app.timestamp] = thingSpeakRead(app.channelID,"ReadKey",app.readKey);
                if (datetime("now") - app.timestamp) > duration(0,0,2)
                    thingSpeakWrite(app.channelID,[app.numPlayers, app.playerTurn,...
                        app.topCardNum,app.playersNumCards(1),app.playersNumCards(2),...
                        app.playersNumCards(3),app.playersNumCards(4)],...
                        "WriteKey",app.writeKey,"Timestamp",datetime("now"));
                    loop = false;
                else
                    pause(2);
                end
            end
        end
        
        function readData(app)
            %simply reads the data for thingspeak and updates the data in
            %the app accordingly

            %call thing speak
            [data,app.timestamp] = thingSpeakRead(app.channelID,"ReadKey",app.readKey);
            %assign data to correct properties
            app.numPlayers = data(1);
            app.playerTurn = data(2);
            app.topCardNum = data(3);
            app.playersNumCards = [data(4), data(5), data(6), data(7)];
        end
        
        function displayHand(app)
            %displays the player's cards as images in the "Your Hand" box
            pathToMLAPP = fileparts(mfilename('fullpath'));
            width = app.YourHandPanel.Position(3);
            numCards = length(app.player.playerHand);
            spacing = (width -20)/(numCards);
            if spacing > 65
                spacing = 65;
            end

            pos = linspace(5,(width-spacing-20),numCards);
            
            images = gobjects(1,numCards);
            for i = 1:numCards
                card = app.player.playerHand(i);
                images(i) = uiimage(app.YourHandPanel,"Position",[pos(i),25,spacing,spacing*1.4],...
                    "ImageClickedFcn",@(src,event)placeCard(app,event,card,i));
                
                file = char(string(pathToMLAPP) + string(card.imageSource));
                images(i).ImageSource = file;
            end
            app.handImages = images;
        end

        function dispTop(app)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            card = app.topCard;
            file = char(string(pathToMLAPP) + string(card.imageSource));
             app.Image.ImageSource = file;

        end

        function clear(app)
           delete(app.handImages)
        end
 
        function placeCard(app, ~, card, index)
            if app.playerTurn == app.myPlayerNum & app.preGame == 0
                if app.checkCardValidity(card)
                    app.topCard = card;
                    app.dispTop
                    app.player.remoCard(index)
                    app.clear
                    app.displayHand
                    if strcmp(card.color, 'wild')
                        app.promptWildCard();
                    else
                        app.finishTurn
                    end
                end
            end
        end
        
        function results = checkCardValidity(app, card)
            if strcmp(card.color, 'wild') %place wild card
                results = true;
            elseif strcmp(app.topCard.number, 'wild') & strcmp(app.topCard.color, card.color) %place on wild card
                results = true;
            elseif strcmp(card.color, app.topCard.color) %place card with same color
                results = true;
            elseif card.number == app.topCard.number %place card with same number
                results = true;
            else
                results = false;
            end
        end

        function assignPlayerValues(app)
            if app.numPlayers <= 3
                app.myPlayerNum = app.numPlayers + 1;
                app.numPlayers = app.numPlayers + 1;
            else
                delete(app)
            end
            
            if app.myPlayerNum == 1
                app.topCard = app.deck.pullCard;
            else 
                app.topCard = Card(app.topCardNum);
                delete(app.StartGameButton)
                app.preGame = false;
            end
            app.topCardNum = app.topCard.encodedCardNumber;
            app.playersNumCards(app.myPlayerNum) = app.player.givelength;
        end
        
        function gameLoop(app)
       
            loop = true;
            while loop
                if app.playerTurn ~= app.myPlayerNum
                    app.readData()
                    app.topCard = Card(app.topCardNum);
                    app.dispTop()
                else
                    %check if game has been quit
                    [data,app.timestamp] = thingSpeakRead(app.channelID,"ReadKey",app.readKey);
                    app.numPlayers = data(1);
                end

                if app.numPlayers == 0
                    delete(app)
                end
                app.dispPlayerNums();
                 
                pause(5)
            end
        end
        
        function finishTurn(app)
            app.updateturn();
            app.topCardNum = app.topCard.encodedCardNumber;
            app.playersNumCards(app.myPlayerNum) = app.player.givelength;
            app.writeData
        end

        function preGameLoop(app)
            while app.preGame == 1
                app.readData()
                app.makeInvisNonPlayers()
              
                pause(1)
            end
            
          
        end
        
        function promptWildCard(app)
            pos = [177, 242, 312, 373];
            text = ["Red", "Blue", "Yellow", "Green"];
            buttons = gobjects(1, 4);
            for i = 1:4
                buttons(i) = uibutton(app.UIFigure, 'push',...
                    'ButtonPushedFcn',@(src,event)promptWildCallback(app, event, i));
                buttons(i).Position = [pos(i), 251, 49, 23];
                buttons(i).Text = text(i);
            end
            app.promptWildButtons = buttons;
        end
        
        function promptWildCallback(app, ~, button)
            %for button: 1 = red, 2 = blue, 3 = yellow, 4 = green
            delete(app.promptWildButtons)
            app.promptWildButtons = [];
            if app.topCard.number == 0
                app.topCard = Card(53 + button);
            else
                app.topCard = Card(57 + button);
            end
            app.dispTop
            app.finishTurn
        end

        function dispPlayerNums(app)
            app.readData()
            if app.myPlayerNum == 1
            app.PlayerXNumber.Text = string(app.playersNumCards(3));
            app.PlayerYNumber.Text = string(app.playersNumCards(2));
            app.PlayerZNumber.Text = string(app.playersNumCards(4));
            end

            if app.myPlayerNum == 2
            app.PlayerXNumber.Text = string(app.playersNumCards(4));
            app.PlayerYNumber.Text = string(app.playersNumCards(1));
            app.PlayerZNumber.Text = string(app.playersNumCards(3));
            end

            if app.myPlayerNum == 3
            app.PlayerXNumber.Text = string(app.playersNumCards(2));
            app.PlayerYNumber.Text = string(app.playersNumCards(4));
            app.PlayerZNumber.Text = string(app.playersNumCards(1));
            end
            

            if app.myPlayerNum == 4
            app.PlayerXNumber.Text = string(app.playersNumCards(1));
            app.PlayerYNumber.Text = string(app.playersNumCards(3));
            app.PlayerZNumber.Text = string(app.playersNumCards(2));
            end


        end
        function makeInvisNonPlayers(app)
            app.readData()
        switch app.myPlayerNum
            case 1
                if app.numPlayers == 1
                    app.PlayerXNumber.Visible = "off";
                    app.PlayerX.Visible = "off";
                    app.PlayerYNumber.Visible = "off";
                    app.PlayerY.Visible = "off";
                    app.PlayerZNumber.Visible = "off";
                    app.PlayerZ.Visible = "off";
                end
                if app.numPlayers == 2
                    app.PlayerXNumber.Visible = "off";
                    app.PlayerX.Visible = "off";
                    app.PlayerYNumber.Visible = "on";
                    app.PlayerY.Visible = "on"; 
                    app.PlayerZNumber.Visible = "off";
                    app.PlayerZ.Visible = "off"; 
                end
                 if app.numPlayers == 3
                    app.PlayerXNumber.Visible = "on";
                    app.PlayerX.Visible = "on";
                    app.PlayerYNumber.Visible = "on";
                    app.PlayerY.Visible = "on"; 
                    app.PlayerZNumber.Visible = "off";
                    app.PlayerZ.Visible = "off"; 
                end
                 if app.numPlayers == 3
                    app.PlayerXNumber.Visible = "on";
                    app.PlayerX.Visible = "on";
                    app.PlayerYNumber.Visible = "on";
                    app.PlayerY.Visible = "on"; 
                    app.PlayerZNumber.Visible = "on";
                    app.PlayerZ.Visible = "on"; 
                 end


       case 2
               
                 if app.numPlayers == 2
                    app.PlayerXNumber.Visible = "off";
                    app.PlayerX.Visible = "off";
                    app.PlayerYNumber.Visible = "on";
                    app.PlayerY.Visible = "on"; 
                    app.PlayerZNumber.Visible = "off";
                    app.PlayerZ.Visible = "off"; 
                 end
                 if app.numPlayers == 3
                    app.PlayerXNumber.Visible = "off";
                    app.PlayerX.Visible = "off";
                    app.PlayerYNumber.Visible = "on";
                    app.PlayerY.Visible = "on"; 
                    app.PlayerZNumber.Visible = "on";
                    app.PlayerZ.Visible = "on"; 
                 end
                 if app.numPlayers == 4
                    app.PlayerXNumber.Visible = "on";
                    app.PlayerX.Visible = "on";
                    app.PlayerYNumber.Visible = "on";
                    app.PlayerY.Visible = "on"; 
                    app.PlayerZNumber.Visible = "on";
                    app.PlayerZ.Visible = "on"; 
                 end

            case 3
                 if app.numPlayers == 3
                    app.PlayerXNumber.Visible = "on";
                    app.PlayerX.Visible = "on";
                    app.PlayerYNumber.Visible = "off";
                    app.PlayerY.Visible = "off"; 
                    app.PlayerZNumber.Visible = "on";
                    app.PlayerZ.Visible = "on"; 
                 end
                 if app.numPlayers == 4
                    app.PlayerXNumber.Visible = "on";
                    app.PlayerX.Visible = "on";
                    app.PlayerYNumber.Visible = "on";
                    app.PlayerY.Visible = "on"; 
                    app.PlayerZNumber.Visible = "on";
                    app.PlayerZ.Visible = "on"; 
                 end

          case 4
                 
                    app.PlayerXNumber.Visible = "on";
                    app.PlayerX.Visible = "on";
                    app.PlayerYNumber.Visible = "on";
                    app.PlayerY.Visible = "on"; 
                    app.PlayerZNumber.Visible = "on";
                    app.PlayerZ.Visible = "on"; 


        end

    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         
            %initialize instances of the deck and players class
            app.deck = Deck();
            app.player = Player(app.deck);
            app.readData()
            app.assignPlayerValues()
            app.writeData()
            %show the user hand on the GUI
            %Show the top card on the GUI
            app.displayHand()  
            app.dispTop()
            %run game loops
            app.preGameLoop()
            app.gameLoop()
            
        end

        % Image clicked function: Image2
        function drawCard(app, event)
            if app.playerTurn == app.myPlayerNum && app.preGame == 0 & isempty(app.promptWildButtons)
                app.player.addCard(app.deck);
                app.clear();
                app.displayHand();
                app.finishTurn();
            end
        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            app.preGame = 0;
              app.makeInvisNonPlayers()
            delete(app.StartGameButton);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %This function is triggered when the game is closed.
            %It erases the data in the thingspeak
            YN = uiconfirm(app.UIFigure,['Do you want to end the game? Ending' ...
                ' the game will close the game for other players (It may ' ...
                'take a moment to close this tab)'], 'Close request');
                if strcmpi(YN,'OK')
                    defaultData = [0,1,0,0,0,0,0];
                    loop = true;
                    while loop
                        [~,app.timestamp] = thingSpeakRead(app.channelID,"ReadKey",app.readKey);
                        if (datetime("now") - app.timestamp) > duration(0,0,10)
                            thingSpeakWrite(app.channelID,defaultData,...
                            "WriteKey",app.writeKey,"Timestamp",datetime("now"));
                            loop = false;
                            delete(app)
                        else
                            pause(2);
                        end
                        
                    end
                end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.YourHandPanel = uipanel(app.UIFigure);
            app.YourHandPanel.Title = 'Your Hand';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UnoCards', 'blue0.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @drawCard, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'UnoCards', 'cardback.png');
            app.DrawCardLabel = uilabel(app.UIFigure);
            app.DrawCardLabel.Text = 'Draw Card';
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.PlayerX = uiimage(app.UIFigure);
            app.PlayerX.ImageSource = fullfile(pathToMLAPP, 'UnoCards', 'cardback.png');
            app.PlayerY = uiimage(app.UIFigure);
            app.PlayerY.ImageSource = fullfile(pathToMLAPP, 'UnoCards', 'cardback.png');
            app.PlayerZ = uiimage(app.UIFigure);
            app.PlayerZ.ImageSource = fullfile(pathToMLAPP, 'UnoCards', 'cardback.png');
            app.PlayerXNumber = uilabel(app.UIFigure);
            app.PlayerXNumber.Text = '7';
            app.PlayerYNumber = uilabel(app.UIFigure);
            app.PlayerYNumber.Text = '7';
            app.PlayerZNumber = uilabel(app.UIFigure);
            app.PlayerZNumber.Text = '7';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uno

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end